classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        StartSimButton              matlab.ui.control.Button
        StopSimButton               matlab.ui.control.Button
        FastSLAMGUILabel            matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        GPUaccelerationSwitchLabel  matlab.ui.control.Label
        GPUaccelerationSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        stop = false;
        simIsRunning = false;
        NParticles = 1000;
        useGPU = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            app.simIsRunning = true;
            p = load_waypoints();
            map = load_map();
            
            nSplines = size(p,2)-3;
            hold(app.UIAxes,"on")
            hold(app.UIAxes2, "on")
            axis(app.UIAxes,[-1 17 -1 15])
            axis(app.UIAxes2,[-1 17 -1 15])
            for idx = 1:size(map,1)
                wall = map(idx,:);
                x = wall(1:2);
                y = wall(3:4);
                plot(app.UIAxes,x,[y(1), y(1)],'k', 'LineWidth', 2);
                plot(app.UIAxes,x,[y(2), y(2)],'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(1), x(1)],y,'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(2), x(2)],y,'k', 'LineWidth', 2);
            end
            % Set axis to gray for map display later on.
            colormap(app.UIAxes2,"gray")
            X = ones(4,app.NParticles);
            M = zeros(app.NParticles,140,160);
            [startPosition, ~] = catmull_spline(p(:,1), p(:,2), p(:,3), p(:,4),0.05);
            X(1:3,:) = repmat(startPosition,1,app.NParticles);
            X(4,:) = 1/app.NParticles;
            for i = 1:nSplines
                p0 = p(:,i);
                p1 = p(:,i+1);
                p2 = p(:,i+2);
                p3 = p(:,i+3);
                for t = 0.05:0.1:0.95
                    tTot = tic;
                    [pose,vel] = catmull_spline(p0, p1, p2, p3, t);
                    v = vecnorm(vel(1:2));  % TODO: Insert noise
                    omega = vel(3);  % TODO: Insert noise
                    % Plotting the path in ground truth map
                    scatter(app.UIAxes, pose(1),pose(2),'Marker', 'x', 'MarkerEdgeColor', 'k');
                    z = scan_sim(pose,map);
                    % Plot scan in ground truth map.
                    zViz = z(:,~any(isnan(z) | isinf(z),1));  % Filter out invalid measurements.
                    zViz(2,:) = mod(zViz(2,:) + pose(3) + pi,2*pi) - pi;
                    scan_pos = [pose(1) + cos(zViz(2,:)).*zViz(1,:);pose(2) + sin(zViz(2,:)).*zViz(1,:)];
                    if exist('scan_plt','var')
                        delete(scan_plt)
                    end
                    scan_plt = scatter(app.UIAxes, scan_pos(1,:),scan_pos(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[1 .0 .0],'LineWidth',0.01);
                    
                    % FastSLAM algorithm
                    tMotion = tic;
                    X = sample_motion_model(v,omega,X);
                    tMotion = toc(tMotion);
                    tZ = tic;
                    X = measurement_model_likelyhood_fields(X, z, M);
                    tZ = toc(tZ);
                    tSampling = tic;
                    [X,sampleIdx] = resampling(X);
                    tSampling = toc(tSampling);
                    tMap = tic;
                    M = updated_occupancy_grid(X, z, M, sampleIdx);
                    tMap = toc(tMap);
                    % Plot map estimate of most likely particle.
                    [~, idx] = max(X(4,:));  % Most likely map.
                    % Minus M because high probabilities are associated
                    % with walls, but should be black in image.
                    im = image(app.UIAxes2, [0,16], [0,14], mat2gray(squeeze(-M(idx,:,:)),[-127,127])*255, 'AlphaData', 0.8, 'CDataMapping', 'direct');
                    if exist('particle_plt','var')
                        delete(particle_plt)
                    end
                    particle_plt = scatter(app.UIAxes2, X(1,:),X(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[.0 1.0 .0],'LineWidth',0.01);
                    drawnow;
                    tTot = toc(tTot);
                    fprintf('Loop times: \n  - motion sample: %.2f s\n  - measurement model: %.2f s\n  - map update: %.2f s\n  - resampling: %.2f s\n  - total: %.2f s\n\n',tMotion,tZ,tMap,tSampling,tTot)
                    if ~((i == nSplines) && (t >= 0.9))
                        delete(im)
                    end
                    if app.stop == true
                        break
                    end
                end
                if app.stop == true
                    break
                end
            end
            app.stop = false;
            app.simIsRunning = false;
            disp('FINISHED SIM')
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            app.stop = true;
            map = load_map();
            cla(app.UIAxes);
            cla(app.UIAxes2);
            hold(app.UIAxes,"on")
            hold(app.UIAxes2, "on")
            axis(app.UIAxes,[-1 17 -1 15])
            axis(app.UIAxes2,[-1 17 -1 15])
            for idx = 1:size(map,1)
                wall = map(idx,:);
                x = wall(1:2);
                y = wall(3:4);
                plot(app.UIAxes,x,[y(1), y(1)],'k', 'LineWidth', 2);
                plot(app.UIAxes,x,[y(2), y(2)],'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(1), x(1)],y,'k', 'LineWidth', 2);
                plot(app.UIAxes,[x(2), x(2)],y,'k', 'LineWidth', 2);
            end
            if ~app.simIsRunning
                app.stop = false;
            end
        end

        % Value changed function: GPUaccelerationSwitch
        function GPUaccelerationSwitchValueChanged(app, event)
            value = app.GPUaccelerationSwitch.Value;
            if strcmp(value, 'On')
                app.useGPU = true;
            else
                app.useGPU = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map and Robot Position')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartSimButton = uibutton(app.UIFigure, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.StopSimButton = uibutton(app.UIFigure, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.FastSLAMGUILabel = uilabel(app.UIFigure);
            app.FastSLAMGUILabel.Text = 'FastSLAM GUI';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Estimated map')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.GPUaccelerationSwitchLabel = uilabel(app.UIFigure);
            app.GPUaccelerationSwitchLabel.Text = 'GPU acceleration';
            app.GPUaccelerationSwitch = uiswitch(app.UIFigure, 'slider');
            app.GPUaccelerationSwitch.ValueChangedFcn = createCallbackFcn(app, @GPUaccelerationSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end